classdef CrackInclination < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        iwstpxEditField         matlab.ui.control.EditField
        iwstpxEditFieldLabel    matlab.ui.control.Label
        StepstpxEditField       matlab.ui.control.EditField
        StepstpxEditFieldLabel  matlab.ui.control.Label
        CrackinclinationwindowiwEditField  matlab.ui.control.NumericEditField
        CrackinclinationwindowiwEditFieldLabel  matlab.ui.control.Label
        RestoreButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        CancelButton            matlab.ui.control.Button
        ApplyButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
        AppData
    end
    
    methods (Access = private)
        
        function updateFields(app)
            iw = app.CrackinclinationwindowiwEditField.Value;
            
            if ~isempty(app.CallingApp.AppData.DICProperties.Step)
                st = str2double(app.StepstpxEditField.Value);
                
                app.iwstpxEditField.Value = num2str(iw*st);
            end
            
            if mod(iw,2) ~= 1
                uialert(app.UIFigure,'iw must be an odd number.','Error');
                return
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position(4) = min([app.UIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.UIFigure.Position(3) = min([app.UIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);
            
            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;
            
            app.CallingApp = mainapp;
            app.AppData.oldProperties = app.CallingApp.AppData.KMProperties;
            app.CrackinclinationwindowiwEditField.Value = app.CallingApp.AppData.KMProperties.iw;
            if ~isempty(app.CallingApp.AppData.DICProperties.Step)
                app.StepstpxEditField.Value = num2str(app.CallingApp.AppData.DICProperties.Step);
            end
            updateFields(app);
        end

        % Value changed function: CrackinclinationwindowiwEditField
        function CrackinclinationwindowiwEditFieldValueChanged(app, event)
            updateFields(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            iw = app.CrackinclinationwindowiwEditField.Value;
            if mod(iw,2) ~= 1
                uialert(app.UIFigure,'iw must be an odd number.','Error');
                return
            end
            app.CallingApp.AppData.KMProperties.iw = iw;
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.AppData.KMProperties = app.AppData.oldProperties;
            delete(app.UIFigure);
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.CrackinclinationwindowiwEditField.Value = 9;
            updateFields(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crack inclination window';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'CrackInclination.png';
            app.RestoreButton = uibutton(app.GridLayout, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.CrackinclinationwindowiwEditFieldLabel = uilabel(app.GridLayout);
            app.CrackinclinationwindowiwEditFieldLabel.Text = 'Crack inclination window iw';
            app.CrackinclinationwindowiwEditField = uieditfield(app.GridLayout, 'numeric');
            app.CrackinclinationwindowiwEditField.Limits = [3 Inf];
            app.CrackinclinationwindowiwEditField.RoundFractionalValues = 'on';
            app.CrackinclinationwindowiwEditField.ValueChangedFcn = createCallbackFcn(app, @CrackinclinationwindowiwEditFieldValueChanged, true);
            app.CrackinclinationwindowiwEditField.Value = 9;
            app.StepstpxEditFieldLabel = uilabel(app.GridLayout);
            app.StepstpxEditFieldLabel.Text = 'Step st [px]';
            app.StepstpxEditField = uieditfield(app.GridLayout, 'text');
            app.StepstpxEditField.Editable = 'off';
            app.iwstpxEditFieldLabel = uilabel(app.GridLayout);
            app.iwstpxEditFieldLabel.Text = 'iw × st [px]';
            app.iwstpxEditField = uieditfield(app.GridLayout, 'text');
            app.iwstpxEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrackInclination(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end