classdef CrackKMProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        DistancesofseparationPanel  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        d1pxEditField               matlab.ui.control.NumericEditField
        d1pxEditFieldLabel          matlab.ui.control.Label
        d2pxEditField               matlab.ui.control.NumericEditField
        d2pxEditFieldLabel          matlab.ui.control.Label
        ConsistencyThresholdPanel   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        MaxerrorEditField           matlab.ui.control.NumericEditField
        maxtolofrmmLabel            matlab.ui.control.Label
        SubsetsspxEditField         matlab.ui.control.EditField
        SubsetsspxLabel             matlab.ui.control.Label
        RestoreButton               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        CancelButton                matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
        AppData
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position(4) = min([app.UIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.UIFigure.Position(3) = min([app.UIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);
            
            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;
            
            app.CallingApp = mainapp;
            
            app.AppData.oldProperties = app.CallingApp.AppData.KMProperties;
            
            
            if ~isempty(app.CallingApp.AppData.KMProperties.d1)
                app.d1pxEditField.Value = app.CallingApp.AppData.KMProperties.d1;
                app.d2pxEditField.Value = app.CallingApp.AppData.KMProperties.d2;
            end
            if ~isempty(app.CallingApp.AppData.DICProperties.Step)
                app.SubsetsspxEditField.Value = num2str(app.CallingApp.AppData.DICProperties.Subset,'%11.4g');
            else
                app.RestoreButton.Enable = 'off';
            end
            app.MaxerrorEditField.Value = app.CallingApp.AppData.KMProperties.reliabilityThreshold;
          
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            app.CallingApp.AppData.KMProperties.d1 = app.d1pxEditField.Value;
            app.CallingApp.AppData.KMProperties.d2 = app.d2pxEditField.Value;
            app.CallingApp.AppData.KMProperties.reliabilityThreshold = app.MaxerrorEditField.Value;
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.AppData.KMProperties = app.AppData.oldProperties;
            delete(app.UIFigure);
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            Menu4_restoreKMParameters(app.CallingApp)
            app.d1pxEditField.Value = app.CallingApp.AppData.KMProperties.d1;
            app.d2pxEditField.Value = app.CallingApp.AppData.KMProperties.d2;
            app.MaxerrorEditField.Value = app.CallingApp.AppData.KMProperties.errorThreshold;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crack Kinematic Measurement Parameters';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'CrackKM.png';
            app.RestoreButton = uibutton(app.GridLayout, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.SubsetsspxLabel = uilabel(app.GridLayout);
            app.SubsetsspxLabel.Text = 'Subset ss [px]';
            app.SubsetsspxEditField = uieditfield(app.GridLayout, 'text');
            app.SubsetsspxEditField.Editable = 'off';
            app.ConsistencyThresholdPanel = uipanel(app.GridLayout);
            app.ConsistencyThresholdPanel.Title = 'Consistency Threshold';
            app.GridLayout3 = uigridlayout(app.ConsistencyThresholdPanel);
            app.maxtolofrmmLabel = uilabel(app.GridLayout3);
            app.maxtolofrmmLabel.Text = 'max. tol. of r [mm]';
            app.MaxerrorEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxerrorEditField.Limits = [0 Inf];
            app.MaxerrorEditField.Value = 0.02;
            app.DistancesofseparationPanel = uipanel(app.GridLayout);
            app.DistancesofseparationPanel.Title = 'Distances of separation';
            app.GridLayout2 = uigridlayout(app.DistancesofseparationPanel);
            app.d2pxEditFieldLabel = uilabel(app.GridLayout2);
            app.d2pxEditFieldLabel.Text = 'd2 [px]';
            app.d2pxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.d2pxEditField.Limits = [0 Inf];
            app.d1pxEditFieldLabel = uilabel(app.GridLayout2);
            app.d1pxEditFieldLabel.Text = 'd1 [px]';
            app.d1pxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.d1pxEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrackKMProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end