classdef DICParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DICParametersUIFigure       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        BlackselectedareasofinterestLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        AreaofinterestListBox       matlab.ui.control.ListBox
        AreaofinterestListBoxLabel  matlab.ui.control.Label
        SteppxEditField             matlab.ui.control.NumericEditField
        SteppxEditFieldLabel        matlab.ui.control.Label
        FilterDropDown              matlab.ui.control.DropDown
        FilterDropDownLabel         matlab.ui.control.Label
        SubsetpxEditField           matlab.ui.control.NumericEditField
        SubsetpxEditFieldLabel      matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        tempfirstfile
        AOI % Description
    end
    
    methods (Access = private)
        
        function plotAOI(app)
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on');
            colormap(app.UIAxes,'gray')
            
            for i = 1: length(app.AOI)
                if any(strcmp(app.AreaofinterestListBox.Value,app.AreaofinterestListBox.Items{i}))
                    imagesc(app.AOI{i}.x(1,:),app.AOI{i}.y(:,1),1-(app.AOI{i}.sigma>0),'Parent',app.UIAxes)
                end
                axis(app.UIAxes,'image')
                ax = app.UIAxes;
                ax.YDir = 'reverse';
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DICFile1, fileName1)
            % Position
            app.DICParametersUIFigure.Position(4) = min([app.DICParametersUIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.DICParametersUIFigure.Position(3) = min([app.DICParametersUIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);
            
            app.DICParametersUIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.DICParametersUIFigure.Position(3)) / 2;
            app.DICParametersUIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.DICParametersUIFigure.Position(4)) / 2;


            app.CallingApp = mainapp;
            app.tempfirstfile = DICFile1;
            
            % set label
            app.Label.Text = ['(sigma-field of ''',fileName1,''')'];
            % get all areas of interest 
            
            names=fieldnames(app.tempfirstfile);
            
            AOIs_x_names=cell(length(names),1);
            AOIs_y_names=cell(length(names),1);
            AOIs_sigma_names = cell(length(names),1);
            AOI_list = cell(length(names),1);
            
            count=0;
            for i=1:length(names)
                if strfind(names{i},'sigma')==1
                    count=count+1;
                end
            end
                        
            % 1st AOI
            AOIs_x_names{1}='x';
            AOIs_y_names{1}='y';
            AOIs_sigma_names{1}='sigma';
            AOI_list{1} = ['AOI ','1'];
            
            % rest of AOIs
            for i=2:count
                AOIs_x_names{i}=['x','_',num2str(i-2)];
                AOIs_y_names{i}=['y','_',num2str(i-2)];
                AOIs_sigma_names{i}=['sigma','_',num2str(i-2)];
                AOI_list{i} = ['AOI ',num2str(i)];
            end


            AOIs_x_names = AOIs_x_names(1:count);
            AOIs_y_names = AOIs_y_names(1:count);
            AOIs_sigma_names = AOIs_sigma_names(1:count);
            AOI_list = AOI_list(1:count);
            app.AreaofinterestListBox.Items = AOI_list;
            
            for i = 1:count
                app.AOI{i}.x = getfield( app.tempfirstfile,AOIs_x_names{i});
                app.AOI{i}.y = getfield( app.tempfirstfile,AOIs_y_names{i});
                app.AOI{i}.sigma = getfield( app.tempfirstfile,AOIs_sigma_names{i});
            end


            % get selected areas of interest
            if isempty(mainapp.AppData.DICProperties.SelectedAOIs)
                app.AreaofinterestListBox.Value = app.AreaofinterestListBox.Items;
            else
                app.AreaofinterestListBox.Value = mainapp.AppData.DICProperties.SelectedAOIs;
            end
            
            
            % get all filters
            if isempty(mainapp.AppData.DICProperties.Filters)
                
                count=0;
                for i=1:length(names)
                    if strfind(names{i},'e1')==1
                        count=count+1;
                        e1_names{count}=names{i};
                    end
                end
                
                filters = length(e1_names)/length(app.AOI);
                
                for i=1:filters
                    filterList{i}=['Filter ',num2str(i)];
                end
                app.FilterDropDown.Items = filterList;
                
            else
                app.FilterDropDown.Items =  mainapp.AppData.DICProperties.Filters;
            end
            
            % get selected filter
            if isempty(mainapp.AppData.DICProperties.SelectedFilter)
                app.FilterDropDown.Value = app.FilterDropDown.Items(1);
            else
                app.FilterDropDown.Value =  mainapp.AppData.DICProperties.SelectedFilter;
            end
            
            
            % set Step
            app.SteppxEditField.Value = mainapp.AppData.DICProperties.Step;
            
            % set Subset
            if isempty(mainapp.AppData.DICProperties.Subset)
                app.SubsetpxEditField.Value = 0;
            else
                app.SubsetpxEditField.Value =  mainapp.AppData.DICProperties.Subset;
            end
            
            drawnow
            plotAOI(app)
            
            
        end

        % Value changed function: SubsetpxEditField
        function SubsetpxEditFieldValueChanged(app, event)
            value = app.SubsetpxEditField.Value;
            if mod(value,2) ~= 1
                
                uialert(app.DICParametersUIFigure,'The DIC subset must be an odd number.','Error');
                %                app.alert=true;
                
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            subset = app.SubsetpxEditField.Value;
            if mod(subset,2) ~= 1
                
                uialert(app.DICParametersUIFigure,'The DIC subset must be an odd number.','Error');
                
                return
            end
            Menu4_setDICParameters(app.CallingApp,[...
                {app.SubsetpxEditField.Value},...
                {app.FilterDropDown.Value},...
                {app.FilterDropDown.Items},...
                {app.AreaofinterestListBox.Value},...
                {app.AreaofinterestListBox.Items}]);
            delete(app.DICParametersUIFigure)
            
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            delete(app.DICParametersUIFigure)
        end

        % Value changed function: AreaofinterestListBox
        function AreaofinterestListBoxValueChanged(app, event)
            plotAOI(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DICParametersUIFigure = uifigure('Visible', 'off');
            app.DICParametersUIFigure.Name = 'DIC Parameters';
            app.GridLayout = uigridlayout(app.DICParametersUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SubsetpxEditFieldLabel = uilabel(app.GridLayout);
            app.SubsetpxEditFieldLabel.Text = 'Subset [px]';
            app.SubsetpxEditField = uieditfield(app.GridLayout, 'numeric');
            app.SubsetpxEditField.ValueChangedFcn = createCallbackFcn(app, @SubsetpxEditFieldValueChanged, true);
            app.SubsetpxEditField.Tooltip = {'odd number'};
            app.FilterDropDownLabel = uilabel(app.GridLayout);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.GridLayout);
            app.SteppxEditFieldLabel = uilabel(app.GridLayout);
            app.SteppxEditFieldLabel.Text = 'Step [px]';
            app.SteppxEditField = uieditfield(app.GridLayout, 'numeric');
            app.SteppxEditField.Enable = 'off';
            app.AreaofinterestListBoxLabel = uilabel(app.GridLayout);
            app.AreaofinterestListBoxLabel.Text = 'Area of interest';
            app.AreaofinterestListBox = uilistbox(app.GridLayout);
            app.AreaofinterestListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', ''};
            app.AreaofinterestListBox.Multiselect = 'on';
            app.AreaofinterestListBox.ValueChangedFcn = createCallbackFcn(app, @AreaofinterestListBoxValueChanged, true);
            app.AreaofinterestListBox.Value = {'Item 1'};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout2);
            app.BlackselectedareasofinterestLabel = uilabel(app.GridLayout2);
            app.BlackselectedareasofinterestLabel.Text = 'Black = selected areas of interest';
            % Show the figure after all components are created
            app.DICParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DICParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DICParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DICParametersUIFigure)
        end
    end
end