classdef Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StrainmagnitudeatuppercolorrangemmmEditField  matlab.ui.control.NumericEditField
        StrainmagnitudeatuppercolorrangemmmEditFieldLabel  matlab.ui.control.Label
        DICStrainMapLabel               matlab.ui.control.Label
        LineSpecificationsLabel         matlab.ui.control.Label
        ErroratuppercolorrangemmEditField  matlab.ui.control.NumericEditField
        ErroratuppercolorrangemmEditFieldLabel  matlab.ui.control.Label
        ColorFillingSpecificationsLabel  matlab.ui.control.Label
        GeneralLabel_2                  matlab.ui.control.Label
        ColorandLineSpecificationsLabel  matlab.ui.control.Label
        CrackwidthatuppercolorrangemmEditField  matlab.ui.control.NumericEditField
        CrackwidthatuppercolorrangemmEditFieldLabel  matlab.ui.control.Label
        MinimumconsecutivemeasuringstageswithcrackwidthsEditField  matlab.ui.control.NumericEditField
        MinimumconsecutivemeasuringstageswithcrackwidthsEditFieldLabel  matlab.ui.control.Label
        SmallcrackkinematicsuppressionLabel  matlab.ui.control.Label
        MinimumplottedcrackwidthmmEditField  matlab.ui.control.NumericEditField
        MinimumplottedcrackwidthmmEditFieldLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        CracklinecolorButton            matlab.ui.control.Button
        CracklinecolorLabel             matlab.ui.control.Label
        KinematicvectorconnectionlinecolorButton  matlab.ui.control.Button
        KinematicvectorconnectionlinecolorLabel  matlab.ui.control.Label
        KinematicvectorconnectionlinewidthpointsEditField  matlab.ui.control.NumericEditField
        KinematicvectorconnectionlinewidthpointsEditFieldLabel  matlab.ui.control.Label
        KinematicvectorlinecolorButton  matlab.ui.control.Button
        KinematicvectorlinecolorLabel   matlab.ui.control.Label
        KinematicvectorintervalEditField  matlab.ui.control.NumericEditField
        KinematicvectorintervalLabel    matlab.ui.control.Label
        KinematicvectorlinewidthpointsEditField  matlab.ui.control.NumericEditField
        KinematicvectorlinewidthpointsEditFieldLabel  matlab.ui.control.Label
        KinematicvectorscrackwidthmagnificationEditField  matlab.ui.control.NumericEditField
        KinematicvectorscrackwidthmagnificationEditFieldLabel  matlab.ui.control.Label
        Scattercrackwidthcircesizepoints2mmEditField  matlab.ui.control.NumericEditField
        Scattercrackwidthcircesizepoints2mmLabel  matlab.ui.control.Label
        MagnificationfactorsLabel       matlab.ui.control.Label
        ContourfillingscrackwidthmagnificationEditField  matlab.ui.control.NumericEditField
        ContourfillingscrackwidthmagnificationEditFieldLabel  matlab.ui.control.Label
        CracklinewidthpointsEditField   matlab.ui.control.NumericEditField
        CracklinewidthpointsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        AppData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position(4) = min([app.UIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.UIFigure.Position(3) = min([app.UIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);
            
            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;
            
            app.CallingApp = mainapp;
            app.AppData.oldProperties = app.CallingApp.AppData.VisualizationParameters;
            
            app.CracklinewidthpointsEditField.Value = app.CallingApp.AppData.VisualizationParameters.CrackLineWidth;
            app.CracklinecolorButton.BackgroundColor = app.CallingApp.AppData.VisualizationParameters.CrackLineColor;
            
            app.StrainmagnitudeatuppercolorrangemmmEditField.Value = app.CallingApp.AppData.VisualizationParameters.MaxStrain;
            
            app.MinimumplottedcrackwidthmmEditField.Value = app.CallingApp.AppData.VisualizationParameters.MinCrackWidth;
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField.Value = app.CallingApp.AppData.VisualizationParameters.MinSteps;
            
            app.CrackwidthatuppercolorrangemmEditField.Value = app.CallingApp.AppData.VisualizationParameters.MaxCrackWidth;
            
            app.ErroratuppercolorrangemmEditField.Value = app.CallingApp.AppData.VisualizationParameters.MaxError;
                        
            app.ContourfillingscrackwidthmagnificationEditField.Value = app.CallingApp.AppData.VisualizationParameters.ContourFfac;
            
            app.Scattercrackwidthcircesizepoints2mmEditField.Value = app.CallingApp.AppData.VisualizationParameters.Scatterfac;
            
            app.KinematicvectorscrackwidthmagnificationEditField.Value = app.CallingApp.AppData.VisualizationParameters.KinVecfac;
            app.KinematicvectorlinewidthpointsEditField.Value = app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineWidth;
            app.KinematicvectorlinecolorButton.BackgroundColor = app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineColor;
            app.KinematicvectorintervalEditField.Value = app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineInterval;
            app.KinematicvectorconnectionlinewidthpointsEditField.Value = app.CallingApp.AppData.VisualizationParameters.KinVecKinLineWidth;
            app.KinematicvectorconnectionlinecolorButton.BackgroundColor = app.CallingApp.AppData.VisualizationParameters.KinVecKinLineColor;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.AppData.VisualizationParameters = app.AppData.oldProperties;
            delete(app.UIFigure);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.CallingApp.AppData.VisualizationParameters.CrackLineWidth = app.CracklinewidthpointsEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.CrackLineColor = app.CracklinecolorButton.BackgroundColor;
            
            app.CallingApp.AppData.VisualizationParameters.MaxStrain = app.StrainmagnitudeatuppercolorrangemmmEditField.Value;
            
                
            app.CallingApp.AppData.VisualizationParameters.MinCrackWidth = app.MinimumplottedcrackwidthmmEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.MinSteps = app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField.Value;
            
            app.CallingApp.AppData.VisualizationParameters.MaxCrackWidth = app.CrackwidthatuppercolorrangemmEditField.Value;
            
            app.CallingApp.AppData.VisualizationParameters.MaxError = app.ErroratuppercolorrangemmEditField.Value;
            
            app.CallingApp.AppData.VisualizationParameters.ContourFfac = app.ContourfillingscrackwidthmagnificationEditField.Value;
            
            app.CallingApp.AppData.VisualizationParameters.Scatterfac = app.Scattercrackwidthcircesizepoints2mmEditField.Value;
            
            app.CallingApp.AppData.VisualizationParameters.KinVecfac = app.KinematicvectorscrackwidthmagnificationEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineWidth = app.KinematicvectorlinewidthpointsEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineColor = app.KinematicvectorlinecolorButton.BackgroundColor;
            app.CallingApp.AppData.VisualizationParameters.KinVecHelpLineInterval = app.KinematicvectorintervalEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.KinVecKinLineWidth = app.KinematicvectorconnectionlinewidthpointsEditField.Value;
            app.CallingApp.AppData.VisualizationParameters.KinVecKinLineColor = app.KinematicvectorconnectionlinecolorButton.BackgroundColor;
            delete(app.UIFigure);
        end

        % Button pushed function: CracklinecolorButton
        function CracklinecolorButtonPushed(app, event)
            color = uisetcolor(app.CracklinecolorButton.BackgroundColor,'Select a color');
            figure(app.UIFigure);
            drawnow
            app.CracklinecolorButton.BackgroundColor = color;
        end

        % Button pushed function: KinematicvectorlinecolorButton
        function KinematicvectorlinecolorButtonPushed(app, event)
            color = uisetcolor(app.KinematicvectorlinecolorButton.BackgroundColor,'Select a color');
            app.KinematicvectorlinecolorButton.BackgroundColor = color;
        end

        % Button pushed function: 
        % KinematicvectorconnectionlinecolorButton
        function KinematicvectorconnectionlinecolorButtonPushed(app, event)
            color = uisetcolor(app.KinematicvectorconnectionlinecolorButton.BackgroundColor,'Select a color');
            app.KinematicvectorconnectionlinecolorButton.BackgroundColor = color;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Visualization Parameters';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CracklinewidthpointsEditFieldLabel = uilabel(app.GridLayout);
            app.CracklinewidthpointsEditFieldLabel.Text = 'Crack line width [points]';
            app.CracklinewidthpointsEditField = uieditfield(app.GridLayout, 'numeric');
            app.CracklinewidthpointsEditField.Limits = [0 10];
            app.CracklinewidthpointsEditField.Value = 1;
            app.ContourfillingscrackwidthmagnificationEditFieldLabel = uilabel(app.GridLayout);
            app.ContourfillingscrackwidthmagnificationEditFieldLabel.Text = 'Contour fillings: crack width magnification [-]';
            app.ContourfillingscrackwidthmagnificationEditField = uieditfield(app.GridLayout, 'numeric');
            app.ContourfillingscrackwidthmagnificationEditField.Limits = [0 Inf];
            app.ContourfillingscrackwidthmagnificationEditField.Value = 5;
            app.MagnificationfactorsLabel = uilabel(app.GridLayout);
            app.MagnificationfactorsLabel.Text = 'Magnification factors ';
            app.Scattercrackwidthcircesizepoints2mmLabel = uilabel(app.GridLayout);
            app.Scattercrackwidthcircesizepoints2mmLabel.Text = 'Scatter: crack width circe size [points^2 / mm] ';
            app.Scattercrackwidthcircesizepoints2mmEditField = uieditfield(app.GridLayout, 'numeric');
            app.Scattercrackwidthcircesizepoints2mmEditField.Limits = [0 Inf];
            app.Scattercrackwidthcircesizepoints2mmEditField.Value = 50;
            app.KinematicvectorscrackwidthmagnificationEditFieldLabel = uilabel(app.GridLayout);
            app.KinematicvectorscrackwidthmagnificationEditFieldLabel.Text = 'Kinematic vectors: crack width magnification [-]';
            app.KinematicvectorscrackwidthmagnificationEditField = uieditfield(app.GridLayout, 'numeric');
            app.KinematicvectorscrackwidthmagnificationEditField.Value = 20;
            app.KinematicvectorlinewidthpointsEditFieldLabel = uilabel(app.GridLayout);
            app.KinematicvectorlinewidthpointsEditFieldLabel.Text = 'Kinematic vector line width [points]';
            app.KinematicvectorlinewidthpointsEditField = uieditfield(app.GridLayout, 'numeric');
            app.KinematicvectorlinewidthpointsEditField.Limits = [0 Inf];
            app.KinematicvectorlinewidthpointsEditField.Value = 0.5;
            app.KinematicvectorintervalLabel = uilabel(app.GridLayout);
            app.KinematicvectorintervalLabel.Text = 'Kinematic vector interval [-]';
            app.KinematicvectorintervalEditField = uieditfield(app.GridLayout, 'numeric');
            app.KinematicvectorintervalEditField.Limits = [1 Inf];
            app.KinematicvectorintervalEditField.RoundFractionalValues = 'on';
            app.KinematicvectorintervalEditField.Value = 3;
            app.KinematicvectorlinecolorLabel = uilabel(app.GridLayout);
            app.KinematicvectorlinecolorLabel.Text = 'Kinematic vector line color';
            app.KinematicvectorlinecolorButton = uibutton(app.GridLayout, 'push');
            app.KinematicvectorlinecolorButton.ButtonPushedFcn = createCallbackFcn(app, @KinematicvectorlinecolorButtonPushed, true);
            app.KinematicvectorlinecolorButton.Text = '';
            app.KinematicvectorconnectionlinewidthpointsEditFieldLabel = uilabel(app.GridLayout);
            app.KinematicvectorconnectionlinewidthpointsEditFieldLabel.Text = 'Kinematic vector connection line width [points]';
            app.KinematicvectorconnectionlinewidthpointsEditField = uieditfield(app.GridLayout, 'numeric');
            app.KinematicvectorconnectionlinewidthpointsEditField.Limits = [0 Inf];
            app.KinematicvectorconnectionlinewidthpointsEditField.Value = 0.5;
            app.KinematicvectorconnectionlinecolorLabel = uilabel(app.GridLayout);
            app.KinematicvectorconnectionlinecolorLabel.Text = 'Kinematic vector connection line color';
            app.KinematicvectorconnectionlinecolorButton = uibutton(app.GridLayout, 'push');
            app.KinematicvectorconnectionlinecolorButton.ButtonPushedFcn = createCallbackFcn(app, @KinematicvectorconnectionlinecolorButtonPushed, true);
            app.KinematicvectorconnectionlinecolorButton.Text = '';
            app.CracklinecolorLabel = uilabel(app.GridLayout);
            app.CracklinecolorLabel.Text = 'Crack line color';
            app.CracklinecolorButton = uibutton(app.GridLayout, 'push');
            app.CracklinecolorButton.ButtonPushedFcn = createCallbackFcn(app, @CracklinecolorButtonPushed, true);
            app.CracklinecolorButton.Text = '';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MinimumplottedcrackwidthmmEditFieldLabel = uilabel(app.GridLayout);
            app.MinimumplottedcrackwidthmmEditFieldLabel.Text = 'Minimum plotted crack width [mm] ';
            app.MinimumplottedcrackwidthmmEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinimumplottedcrackwidthmmEditField.Value = 0.01;
            app.SmallcrackkinematicsuppressionLabel = uilabel(app.GridLayout);
            app.SmallcrackkinematicsuppressionLabel.Text = 'Small crack kinematic suppression';
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditFieldLabel = uilabel(app.GridLayout);
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditFieldLabel.Text = {'Minimum consecutive measuring stages'; ' with crack widths above threshold [-]'};
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField.Limits = [1 Inf];
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField.RoundFractionalValues = 'on';
            app.MinimumconsecutivemeasuringstageswithcrackwidthsEditField.Value = 1;
            app.CrackwidthatuppercolorrangemmEditFieldLabel = uilabel(app.GridLayout);
            app.CrackwidthatuppercolorrangemmEditFieldLabel.Text = 'Crack width at upper color range [mm]';
            app.CrackwidthatuppercolorrangemmEditField = uieditfield(app.GridLayout, 'numeric');
            app.CrackwidthatuppercolorrangemmEditField.Value = 2;
            app.ColorandLineSpecificationsLabel = uilabel(app.GridLayout);
            app.ColorandLineSpecificationsLabel.Text = 'Color and Line Specifications';
            app.GeneralLabel_2 = uilabel(app.GridLayout);
            app.GeneralLabel_2.Text = 'General';
            app.ColorFillingSpecificationsLabel = uilabel(app.GridLayout);
            app.ColorFillingSpecificationsLabel.Text = 'Color Filling Specifications';
            app.ErroratuppercolorrangemmEditFieldLabel = uilabel(app.GridLayout);
            app.ErroratuppercolorrangemmEditFieldLabel.Text = 'Error at upper color range [mm]';
            app.ErroratuppercolorrangemmEditField = uieditfield(app.GridLayout, 'numeric');
            app.ErroratuppercolorrangemmEditField.Value = 0.02;
            app.LineSpecificationsLabel = uilabel(app.GridLayout);
            app.LineSpecificationsLabel.Text = 'Line Specifications';
            app.DICStrainMapLabel = uilabel(app.GridLayout);
            app.DICStrainMapLabel.Text = 'DIC Strain Map';
            app.StrainmagnitudeatuppercolorrangemmmEditFieldLabel = uilabel(app.GridLayout);
            app.StrainmagnitudeatuppercolorrangemmmEditFieldLabel.Text = 'Strain magnitude at upper color range [mm/m]';
            app.StrainmagnitudeatuppercolorrangemmmEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end