classdef CrackLengthSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SelectButton                 matlab.ui.control.RadioButton
        DelectButton                 matlab.ui.control.RadioButton
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        LongercracksButton           matlab.ui.control.RadioButton
        ShortercracksButton          matlab.ui.control.RadioButton
        ApplyButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        CracklengthmmEditFieldLabel  matlab.ui.control.Label
        CracklengthmmEditField       matlab.ui.control.NumericEditField
    end

   
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;
                        
            app.CallingApp = mainapp;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            len = app.CracklengthmmEditField.Value;
            select = app.SelectButton.Value;
            longer = app.LongercracksButton.Value;
            app.CallingApp.tempData = [len,select,longer];
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectButton = uiradiobutton(app.ButtonGroup);
            app.SelectButton.Text = 'Select';
            app.SelectButton.Value = true;
            app.DelectButton = uiradiobutton(app.ButtonGroup);
            app.DelectButton.Text = 'Delect';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.LongercracksButton = uiradiobutton(app.ButtonGroup_2);
            app.LongercracksButton.Text = 'Longer cracks';
            app.LongercracksButton.Value = true;
            app.ShortercracksButton = uiradiobutton(app.ButtonGroup_2);
            app.ShortercracksButton.Text = 'Shorter cracks';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CracklengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.CracklengthmmEditFieldLabel.Text = 'Crack length [mm]:';
            app.CracklengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CracklengthmmEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrackLengthSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end