classdef Tool_CrackModification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        NoninteractiveplotLabel    matlab.ui.control.Label
        ModifycrackbranchesButton  matlab.ui.control.StateButton
        UpdatecrackfileButton      matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp
        tempAppData
        originalAppData

        Color
        dlg

        ROImodify
        ROImodify_number
    end

    methods (Access = private)

        function closedlg(app)
            if ~isempty(app.dlg)
                close(app.dlg)
            end
        end

        function plotCrackLines(app)

            % plot Crack line
            [x,y,~,~] = Tab1_getCrackLinePlotData(app.CallingApp,app.originalAppData.BRANCH,app.originalAppData.NODE,'px',false);
            %             line(app.UIAxes,x,y,'Color',app.Color.deleted,'ZData',x*0+100); % do not set linewidth!! problem occured
            pl = plot(app.UIAxes,x,y,'ZData',x*0+100*0);
            pl.Color = app.Color.deleted;


            % create temporary removed crack lines
            [x,y,~,~] = Tab1_getCrackLinePlotData(app.CallingApp,app.tempAppData.BRANCH,app.tempAppData.NODE,'px',false);
            pl = plot(app.UIAxes,x,y,'Color','k','ZData',x*0+100*0);
            pl.Color = app.CallingApp.AppData.VisualizationParameters.CrackLineColor;

        end

        function Fig1_initialeBranchLines(app,ax,tempf,restorebackground)

            % set ROI instance
            app.ROImodify = images.roi.Polygon;
            app.ROImodify_number = [];

            activeAOI = [];

            if restorebackground
                % ContextMenu of background
                cm = uicontextmenu(tempf);
                cm.ContextMenuOpeningFcn = {@highlightBranch,app,[]};
                m1 = uimenu(cm,'Text','Remove cracks in polygonal area');
                m2 = uimenu(cm,'Text','Add crack polyline');
                m1.MenuSelectedFcn = {@DrawPolyon,app,ax};
                m2.MenuSelectedFcn = {@DrawPolyLine,app,ax};

                tempf.ContextMenu = cm;

                get(ax.Children(1),'ButtonDownFcn');
                for i = 1:length(ax.Children)
                    ax.Children(i).ContextMenu = cm;
                    set(ax.Children(i),'ButtonDownFcn',{@clickCallback,app});
                end
            end


            for i = length(app.tempAppData.p)+1:length(app.tempAppData.BRANCH) % current crack lines


                [x,y,~,~] = Tab1_getCrackLinePlotData(app.CallingApp,app.tempAppData.BRANCH(i),app.tempAppData.NODE,'px',false);
                app.tempAppData.p{i}= plot(ax,x,y,'ZData',x*0+100*0,...
                    'Color',app.CallingApp.AppData.VisualizationParameters.CrackLineColor);

                cm = uicontextmenu(tempf);
                m1 = uimenu(cm,'Text','Modify crack');
                m2 = uimenu(cm,'Text','Delete crack');
                m3 = uimenu(cm,'Text','Restore crack');
                cm.ContextMenuOpeningFcn = {@highlightBranch,app,i};
                m1.MenuSelectedFcn = {@LineModificationFcn,app,i,ax};
                m2.MenuSelectedFcn = {@LineDeletionFcn,app,i};
                m3.MenuSelectedFcn = {@LineRestoringFcn,app,i};

                app.tempAppData.p{i}.UIContextMenu = cm;
            end


            %%% Anonymous Callbacks
            function DrawPolyLine(~,~,app,ax)

                roi = drawpolyline(gca,'Color','g');


                if isvalid(roi) && ~isempty(roi.Position)
                    x = roi.Position(:,1);
                    y = roi.Position(:,2);

                    for j=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)
                        AOI{j}.xlimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:))];
                        AOI{j}.ylimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1))];
                    end

                    % get drawable area
                    for j = 1:length(AOI)
                        if x(1) >= AOI{j}.xlimits(1) && ...
                                x(1) <= AOI{j}.xlimits(2) &&...
                                y(1) >= AOI{j}.ylimits(1) && ...
                                y(1) <= AOI{j}.ylimits(2)

                            activeAOI = j;
                            break
                        end
                    end

                    % add line
                    i = length(app.tempAppData.BRANCH)+1;

                    app.tempAppData.BRANCH(i).x = x;
                    app.tempAppData.BRANCH(i).y = y;
                    app.tempAppData.BRANCH(i).no = i;
                    app.tempAppData.BRANCH(i).nodes = [];

                    roiBW = line2raster(app,roi);

                    app.tempAppData.ROItoadd{i}=roiBW{activeAOI};
                    app.tempAppData.modified(i) = 1;
                    app.tempAppData.removed(i) = 0;
                    app.tempAppData.highlighted(i) = 0;
                    app.tempAppData.highlighted(:) = 0;

                    Fig1_initialeBranchLines(app,ax,tempf,false)
                    Fig1_updateLines(app,i)

                    delete(roi);

                    app.tempAppData.crackfileupdated = 0;

                end

            end

            function DrawPolyon(~,~,app,ax)

                roi = drawpolygon(gca,'Color','r');

                if isvalid(roi)
                    cm = uicontextmenu(gcf);
                    m1 = uimenu(cm,'Text','Remove cracks inside area');
                    m2 = uimenu(cm,'Text','Delete area');
                    m1.MenuSelectedFcn = {@RemoveCracksInPolyon,app,roi,ax};
                    m2.MenuSelectedFcn = {@RemovePolyon,roi};

                    roi.ContextMenu = cm;
                end

                function RemoveCracksInPolyon(~,~,app,roi,ax)

                    x = roi.Position(:,1);
                    y = roi.Position(:,2);

                    if isvalid(roi)

                        roiBW = line2raster(app,roi);

                        delete(roi);

                        for j=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)

                            app.tempAppData.ROItoremove{j} = app.tempAppData.ROItoremove{j}+roiBW{j};
                            app.tempAppData.ROItoremove{j} = (app.tempAppData.ROItoremove{j}>0);

                            color = repmat(app.tempAppData.ROItoremove{j},[1,1,3]);
                            color(:,:,1) = color(:,:,1)*1;
                            color(:,:,2) = color(:,:,2)*0;
                            color(:,:,3) = color(:,:,3)*0;
                            imagesc(...
                                app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:),...
                                app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1),...
                                color,...
                                'AlphaData',app.tempAppData.ROItoremove{j}*1 ...
                                );
                        end
                        app.tempAppData.crackfileupdated = 0;


                        ch = get(gca,'Children');
                        uistack(ch(1),'down',length(ch)-4);
                    end

                end

                function RemovePolyon(~,~,roi)
                    delete(roi)
                end

            end

            function LineModificationFcn(~,~,app,i,ax)

                title(ax,'confirm crack line modification before closing','Color', 'k','FontWeight',"normal",'FontAngle',"italic");

                x = app.tempAppData.BRANCH(i).x;
                y = app.tempAppData.BRANCH(i).y;

                for j=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)
                    AOI{j}.xlimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:))];
                    AOI{j}.ylimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1))];
                end

                for j = 1:length(AOI)
                    if x(1) >= AOI{j}.xlimits(1) && ...
                            x(1) <= AOI{j}.xlimits(2) &&...
                            y(1) >= AOI{j}.ylimits(1) && ...
                            y(1) <= AOI{j}.ylimits(2)

                        drawingarea=[AOI{j}.xlimits(1),...
                            AOI{j}.ylimits(1),...
                            AOI{j}.xlimits(2)-AOI{j}.xlimits(1),...
                            AOI{j}.ylimits(2)-AOI{j}.ylimits(1)];
                        activeAOI = j;
                        break
                    end
                end

                app.ROImodify = drawpolyline(gca,"Position",[x,y],'DrawingArea',drawingarea);
                app.ROImodify.Color = 'g';

                app.ROImodify_number = i;


                %%
                for i = 1:length(app.tempAppData.p) % current crack lines
                    app.tempAppData.p{i}.UIContextMenu = [];
                end
                %%

            end


            function LineDeletionFcn(~,~,app,i)
                app.tempAppData.highlighted(i) = 0;
                app.tempAppData.removed(i) = 1;
                Fig1_updateLines(app,i)
            end

            function LineRestoringFcn(~,~,app,i)
                app.tempAppData.highlighted(i) = 0;
                app.tempAppData.removed(i) = 0;
                Fig1_updateLines(app,i)
            end

            function clickCallback(src,evt,app)

                if ~isempty(app.ROImodify_number)

                    if isvalid(app.ROImodify)
                        opts.Interpreter = 'none';
                        opts.Default = 'Apply';
                        answer = questdlg('Apply crack branch modification?',...
                            'Confirm changes',...
                            'Apply','Discard','Cancel',opts);

                        if strcmp(answer,'Cancel') || isempty(answer)
                            return
                        end

                        %%
                        for i = 1:length(app.tempAppData.p) % current crack lines

                            cm = uicontextmenu(tempf);
                            m1 = uimenu(cm,'Text','Modify crack');
                            m2 = uimenu(cm,'Text','Delete crack');
                            m3 = uimenu(cm,'Text','Restore crack');
                            cm.ContextMenuOpeningFcn = {@highlightBranch,app,i};
                            m1.MenuSelectedFcn = {@LineModificationFcn,app,i,ax};
                            m2.MenuSelectedFcn = {@LineDeletionFcn,app,i};
                            m3.MenuSelectedFcn = {@LineRestoringFcn,app,i};

                            app.tempAppData.p{i}.UIContextMenu = cm;
                        end
                        %%

                        if strcmp(answer,'Discard') || isempty(answer)
                            delete(app.ROImodify);
                            app.ROImodify_number = [];
                            highlightBranch(src,evt,app,[])
                            return
                        end

                        i = app.ROImodify_number;
                        app.tempAppData.BRANCH(i).x = app.ROImodify.Position(:,1);
                        app.tempAppData.BRANCH(i).y = app.ROImodify.Position(:,2);
                        app.tempAppData.BRANCH(i).nodes = [];

                        app.tempAppData.p{i}.XData = app.ROImodify.Position(:,1)';
                        app.tempAppData.p{i}.YData = app.ROImodify.Position(:,2)';
                        app.tempAppData.p{i}.ZData = app.tempAppData.p{i}.XData*0+100*0;

                        roiBW = line2raster(app,app.ROImodify);

                        app.tempAppData.ROItoadd{i}=roiBW{activeAOI};
                        app.tempAppData.highlighted(i) = 0;
                        app.tempAppData.modified(i) = 1;
                        app.tempAppData.removed(i) = 0;
                        Fig1_updateLines(app,i)

                        delete(app.ROImodify);
                        app.ROImodify_number = [];

                        app.tempAppData.crackfileupdated = 0;

                        title(ax,'right-click on crack lines to modify / delete','Color', 'k','FontWeight',"normal",'FontAngle',"italic");

                    end


                end
                highlightBranch(src,evt,app,[])


                %                 uiresume(gcbf);
            end

            function highlightBranch(~,~,app,i)

                if isfield(app.tempAppData,'highlighted')
                    result = find(app.tempAppData.highlighted == 1);
                    if ~isempty(result)
                        app.tempAppData.highlighted(result) = 0;
                        Fig1_updateLines(app,result)
                    end

                    if ~isempty(i)
                        app.tempAppData.highlighted(i) = 1;
                        Fig1_updateLines(app,i)
                    end
                end

            end
        end

        function Fig1_updateLines(app,i)

            if app.tempAppData.removed(i)
                app.tempAppData.p{i}.Color = app.Color.removed;
            else
                app.tempAppData.p{i}.Color = 'k';
            end
            if app.tempAppData.highlighted(i)
                app.tempAppData.p{i}.Color = app.Color.highlighted;
            end

        end


        function removeBRANCHs(app)
            if ~isempty(app.tempAppData.BRANCH)
                BRANCH=app.tempAppData.BRANCH(1);

                count=0;
                for i = 1:length(app.tempAppData.BRANCH)
                    if ~app.tempAppData.removed(i)
                        count=count+1;
                        BRANCH(count)=app.tempAppData.BRANCH(i);
                        modified(count)=app.tempAppData.modified(i);
                        ROI{count}=app.tempAppData.ROItoadd{i};
                    else
                        app.tempAppData.crackfileupdated = 0;
                    end
                end
                if count == 0
                    BRANCH = [];
                    modified = [];
                    ROI = [];
                end

                app.tempAppData.BRANCH = BRANCH;
                app.tempAppData.modified = modified;
                app.tempAppData.removed = modified*0;
                app.tempAppData.highlighted = modified*0;
                app.tempAppData.ROItoadd = ROI;

                for i = 1:length(app.tempAppData.BRANCH)
                    app.tempAppData.removed(i) = 0;
                    app.tempAppData.highlighted(i) = 0;
                end
            end
        end

        function resetplot(app,axes)

            app.CallingApp.LocalAppData.TabPlot(10).reset = 1;
            uiplot(app.CallingApp,10,axes)

            [~,btns] = axtoolbar(axes,{'export','pan','zoomin','zoomout','restoreview'});
            btns(1).ButtonPushedFcn = {@restoreview,app,axes};

            % Axes Toolbar Callbacks
            function restoreview(~,~,app,axes)

                resetplot(app,axes)
                plotCrackLines(app)

            end

            xlimits = xlim(axes);
            ylimits = ylim(axes);
            x = [xlimits(1),xlimits(2),xlimits(2),xlimits(1)];
            y = [ylimits(1),ylimits(1),ylimits(2),ylimits(2)];
            p=patch(axes,x,y,'w','ZData',x*0+5*0);
            set(p,'FaceAlpha',0.8);
            set(p,'EdgeColor','none')


            for j=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)

                color = repmat(app.tempAppData.ROItoremove{j},[1,1,3]);
                color(:,:,1) = color(:,:,1)*1;
                color(:,:,2) = color(:,:,2)*0;
                color(:,:,3) = color(:,:,3)*0;
                imagesc(axes,...
                    app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:),...
                    app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1),...
                    color,...
                    'AlphaData',app.tempAppData.ROItoremove{j}*1 ...
                    );

            end


            [x,y,~,~] = Tab1_getCrackLinePlotData(app.CallingApp,app.originalAppData.BRANCH,app.originalAppData.NODE,'px',false);
            plot(axes,x,y,'Color','r','ZData',x*0+100*0); % do not set linewidth!! problem occured

        end

        function roiBW = line2raster(app,ROI)

            for i=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)
                AOI{i}.xlimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x(1,:)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x(1,:))];
                AOI{i}.ylimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.y(:,1)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.y(:,1))];
                BW{i} = app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x*0;
            end

            for j = 1:length(AOI)

                tempimage = imagesc( ...
                    app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x(1,:),app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.y(:,1), ...
                    BW{j},'Visible','off');
                roiBW{j} = createMask(ROI,tempimage);
                delete(tempimage)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIFigureStartupFcn(app, mainapp)

            % set UIFigure position
            app.UIFigure.Position(4) = min([app.UIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.UIFigure.Position(3) = min([app.UIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);

            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;


            app.dlg = uiprogressdlg(app.UIFigure,'Title',['Please wait..'],...
                'Indeterminate','on');
            % set AppData
            app.CallingApp = mainapp;

            app.originalAppData.BW = mainapp.AppData.BW;
            app.originalAppData.BRANCH = mainapp.AppData.BRANCH;
            app.originalAppData.NODE = mainapp.AppData.NODE;

            app.tempAppData.BW = mainapp.AppData.BW;
            app.tempAppData.BRANCH = mainapp.AppData.BRANCH;
            app.tempAppData.NODE = mainapp.AppData.NODE;

            for i = 1:length(app.tempAppData.BRANCH)
                app.tempAppData.ROItoadd{i}=[];
                app.tempAppData.modified(i) = 0;
                app.tempAppData.removed(i) = 0;
                app.tempAppData.highlighted(i) = 0;
            end

            for j=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)
                app.tempAppData.ROItoremove{j} = app.CallingApp.LocalAppData.DICCoordinates.AOI{j}.x*0;
            end

            app.tempAppData.crackfileupdated = 1;

            app.Color.removed =  [255,165,0]/255;
            app.Color.highlighted = [1,1,1]*0.6;
            app.Color.deleted = [255,0,0]/255;

            resetplot(app,app.UIAxes);
            plotCrackLines(app)
            drawnow
            closedlg(app)
        end

        % Value changed function: ModifycrackbranchesButton
        function ModifycrackbranchesButtonValueChanged(app, event)

            %% Plot
            app.dlg = uiprogressdlg(app.UIFigure,'Title','Modifying manually crack branches ',...
                'Indeterminate','on');

            % initiate interactive plot
            tempf = figure(1);
            tempf.Position = app.UIAxes.Position;
            tempf.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-app.UIAxes.Position(3)/2;
            tempf.Position(2) = app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-app.UIAxes.Position(4)/2;

            clf
            ax = axes('Parent',tempf);
            resetplot(app,ax);

            tempf.Name = 'Crack modification';
            title(ax,'right-click to add / delete or modify','Color', 'k','FontWeight',"normal",'FontAngle',"italic");

            set(tempf, 'MenuBar', 'none');
            set(tempf, 'ToolBar', 'none');
            axtoolbar(ax,{'export','pan','zoomin','zoomout','restoreview'});

            tempf.DeleteFcn =  {@deletetempf,app};

            function deletetempf(~,~,app)
                % After closing tempf
                app.ModifycrackbranchesButton.Value = 0;
                app.tempAppData.p = [];

                removeBRANCHs(app)
                resetplot(app,app.UIAxes);
                plotCrackLines(app)
                drawnow;

                closedlg(app)


            end

            % Draw Bracnh Lines
            app.tempAppData.p = [];
            Fig1_initialeBranchLines(app,ax,tempf,true)


        end

        % Button pushed function: UpdatecrackfileButton
        function UpdatecrackfileButtonPushed(app, event)


            app.dlg = uiprogressdlg(app.UIFigure,'Title','Updating Crack file',...
                'Indeterminate','on',"Cancelable",'on');
            step = app.CallingApp.AppData.DICProperties.Step;
            for i=1:length(app.CallingApp.LocalAppData.DICFiles{1}.AOI)
                AOI{i}.xlimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x(1,:)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x(1,:))];
                AOI{i}.ylimits = [min(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.y(:,1)) , max(app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.y(:,1))];
                BW{i} = app.CallingApp.LocalAppData.DICCoordinates.AOI{i}.x*0;
            end

            for i = 1:length(app.tempAppData.BRANCH)
                if app.dlg.CancelRequested

                    return
                end
                for j = 1:length(AOI)
                    if app.tempAppData.BRANCH(i).x(1) >= AOI{j}.xlimits(1) && ...
                            app.tempAppData.BRANCH(i).x(1) <= AOI{j}.xlimits(2) &&...
                            app.tempAppData.BRANCH(i).y(1) >= AOI{j}.ylimits(1) && ...
                            app.tempAppData.BRANCH(i).y(1) <= AOI{j}.ylimits(2)

                        if app.tempAppData.modified(i)
                            bw = app.tempAppData.ROItoadd{i};
                            BW{j}=BW{j}+bw;
                        else
                            for l=1:length(app.tempAppData.BRANCH(i).x)
                                x = app.tempAppData.BRANCH(i).x(l);
                                y = app.tempAppData.BRANCH(i).y(l);

                                xx = (x-AOI{j}.xlimits(1))/step + 1;
                                yy = (y-AOI{j}.ylimits(1))/step + 1;
                                BW{j}(yy,xx) = 1;
                            end

                            for n = 1: length(app.tempAppData.BRANCH(i).nodes)
                                x = app.tempAppData.NODE(app.tempAppData.BRANCH(i).nodes(n)).x;
                                y = app.tempAppData.NODE(app.tempAppData.BRANCH(i).nodes(n)).y;

                                xx = (x-AOI{j}.xlimits(1))/step + 1;
                                yy = (y-AOI{j}.ylimits(1))/step + 1;

                                BW{j}(yy,xx) = 1;
                            end
                        end
                        break
                    end
                end
            end
            empty = true;
            for j = 1:length(AOI)
                BW{j}=(BW{j}>0);

                BW{j}=BW{j}-app.tempAppData.ROItoremove{j};
                BW{j}=(BW{j}>0);

                BW{j} = Tab1_thinBWCracks(app.CallingApp,BW{j});


                if sum(BW{j}(:))>0
                    empty = false;
                end
            end


            [BRANCH, NODE] =  Tab1_getBranchesAndNodes(app.CallingApp,BW,app.UIFigure);
            if isempty(BRANCH) && ~empty
                % cancel request
                return
            end
            BRANCH = Tab1_setInclination(app.CallingApp,BRANCH);
            BRANCH = Tab1_setBRANCHLenth(app.CallingApp,BRANCH);

            Tab1_setCrackData(app.CallingApp,[...
                {BW},...
                {BRANCH},...
                {NODE}]);


            app.tempAppData.BW = app.CallingApp.AppData.BW;
            app.tempAppData.BRANCH = app.CallingApp.AppData.BRANCH;
            app.tempAppData.NODE = app.CallingApp.AppData.NODE;

            assignin("base","lBRANCH",app.tempAppData.BRANCH)

            for i = 1:length(app.tempAppData.BRANCH)
                app.tempAppData.ROItoadd{i}=[];
                app.tempAppData.modified(i) = 0;
                app.tempAppData.removed(i) = 0;
                app.tempAppData.highlighted(i) = 0;
            end

            resetplot(app,app.UIAxes);

            plotCrackLines(app)

            app.tempAppData.crackfileupdated = 1;
            drawnow;
            assignin('base','tempAppData',app.tempAppData);
            delete(app.dlg);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.tempAppData.crackfileupdated == 0
                uiconfirm(app.UIFigure,'Cannot proceed without updating crack file','Error',...
                    'Options',{'Ok'},...
                    "Icon",'error',...
                    'DefaultOption',1,'CancelOption',1);
                return
            end
            Tab1_setCrackData(app.CallingApp,[...
                {app.tempAppData.BW},...
                {app.tempAppData.BRANCH},...
                {app.tempAppData.NODE}]);

            %%
            c1 = isequaln(app.tempAppData.BW, app.originalAppData.BW);
            c2 = isequaln(app.tempAppData.BRANCH,app.originalAppData.BRANCH);
            c3 = isequaln(app.tempAppData.NODE,app.originalAppData.NODE);

            if sum([c1,c2,c3])<3
                Tab2_resetFamilies(app.CallingApp)
                app.CallingApp.AppData.FAMILY=struct('Name',[],'Color',[]);
                app.CallingApp.CrackfamiliesListBox.Items = {};
                app.CallingApp.CrackfamiliesListBox_2.Items = {};

                removeKinematics(app.CallingApp)
                removeSmoothedValues(app.CallingApp)


                app.CallingApp.LocalAppData.TabPlot(1).reset = 1;
                app.CallingApp.LocalAppData.TabPlot(2).reset = 1;
                app.CallingApp.LocalAppData.TabPlot(3).reset = 1;


                if ~isempty(app.CallingApp.AppData.cracklineplotdata)
                    app.CallingApp.CracklinesCheckBox.Value = 1;
                    app.CallingApp.CracklinesCheckBox_2.Value = 1;
                    app.CallingApp.CracklinesCheckBox_3.Value = 1;

                end
                setInteractivities(app.CallingApp);
                uiplot(app.CallingApp,1);
                uiplot(app.CallingApp,2);

            end
            %%

            closedlg(app.CallingApp)
            delete(app.UIFigure)
        end

        % Callback function: CancelButton, UIFigure
        function CancelButtonPushed(app, event)

            c1 = isequaln(app.tempAppData.BW, app.originalAppData.BW);
            c2 = isequaln(app.tempAppData.BRANCH,app.originalAppData.BRANCH);
            c3 = isequaln(app.tempAppData.NODE,app.originalAppData.NODE);

            if sum([c1,c2,c3])<3
                selection = uiconfirm(app.UIFigure,'Changes will not be saved. Do you want to proceed?','Warning: Delete data',...
                    'Icon','warning','Options',{'Discard Changes','Cancel'},...
                    'DefaultOption',2,'CancelOption',2);
                if strcmp(selection,'Cancel')
                    return
                end
            end

            Tab1_setCrackData(app.CallingApp,[...
                {app.originalAppData.BW},...
                {app.originalAppData.BRANCH},...
                {app.originalAppData.NODE}]);

            closedlg(app.CallingApp)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crack Pattern Modification Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UpdatecrackfileButton = uibutton(app.GridLayout, 'push');
            app.UpdatecrackfileButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatecrackfileButtonPushed, true);
            app.UpdatecrackfileButton.Text = 'Update crack file';
            app.ModifycrackbranchesButton = uibutton(app.GridLayout, 'state');
            app.ModifycrackbranchesButton.ValueChangedFcn = createCallbackFcn(app, @ModifycrackbranchesButtonValueChanged, true);
            app.ModifycrackbranchesButton.Text = 'Modify crack branches...';
            app.NoninteractiveplotLabel = uilabel(app.GridLayout);
            app.NoninteractiveplotLabel.Text = 'Non-interactive plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tool_CrackModification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)UIFigureStartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end