classdef Tool_Smoothing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ApplyButton                   matlab.ui.control.Button
        DeletesmootheddataButton      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        MethodDropDown                matlab.ui.control.DropDown
        MethodLabel                   matlab.ui.control.Label
        MethodDropDown_3              matlab.ui.control.DropDown
        MethodLabel_3                 matlab.ui.control.Label
        FilterspanEditField_2         matlab.ui.control.NumericEditField
        FilterspanEditField_2Label    matlab.ui.control.Label
        MethodDropDown_2              matlab.ui.control.DropDown
        MethodLabel_2                 matlab.ui.control.Label
        InterpolateCheckBox           matlab.ui.control.CheckBox
        CrackkinematicsmoothingLabel  matlab.ui.control.Label
        FilterspanEditField           matlab.ui.control.NumericEditField
        FilterspanEditFieldLabel      matlab.ui.control.Label
        CracklinesmoothingLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        AppData % Description % Description
    end
    
    methods (Access = private)
        
        function checkvalues(app)
            
            if mod(app.FilterspanEditField.Value-1,2) ~= 0
                app.FilterspanEditField.Value = app.FilterspanEditField.Value + 1;
            end
            
            if mod(app.FilterspanEditField_2.Value-1,2) ~= 0
                app.FilterspanEditField_2.Value = app.FilterspanEditField_2.Value + 1;
            end
        end
        
        function setinteractivities(app)
            if app.InterpolateCheckBox.Value
                app.MethodDropDown_2.Enable = 'on';
                app.FilterspanEditField_2.Enable = 'on';
                app.MethodDropDown_3.Enable = 'on';
            else
                app.MethodDropDown_2.Enable = 'off';
                app.FilterspanEditField_2.Enable = 'off';
                app.MethodDropDown_3.Enable = 'off';
            end
            
            if app.FilterspanEditField.Value > 1
                app.MethodDropDown.Enable = 'on';
            else
                app.MethodDropDown.Enable = 'off';
            end
            
            if app.FilterspanEditField_2.Value > 1 && app.InterpolateCheckBox.Value
                app.MethodDropDown_3.Enable = 'on';
            else
                app.MethodDropDown_3.Enable = 'off';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position(4) = min([app.UIFigure.Position(4),mainapp.ACDMUIFigure.Position(4)]);
            app.UIFigure.Position(3) = min([app.UIFigure.Position(3),mainapp.ACDMUIFigure.Position(3)]);
            
            app.UIFigure.Position(1) = mainapp.ACDMUIFigure.Position(1) + ...
                (mainapp.ACDMUIFigure.Position(3) - app.UIFigure.Position(3)) / 2;
            app.UIFigure.Position(2) = mainapp.ACDMUIFigure.Position(2) + ...
                (mainapp.ACDMUIFigure.Position(4) - app.UIFigure.Position(4)) / 2;
            
            app.CallingApp = mainapp;
            
            app.FilterspanEditField.Value = app.CallingApp.AppData.SmoothingParameters.CLSpan;
            app.MethodDropDown.Value = app.CallingApp.AppData.SmoothingParameters.CLMethod;
            
            app.InterpolateCheckBox.Value = app.CallingApp.AppData.SmoothingParameters.KInterpolate;
            app.MethodDropDown_2.Value = app.CallingApp.AppData.SmoothingParameters.KInterpolateMethod;
            app.FilterspanEditField_2.Value = app.CallingApp.AppData.SmoothingParameters.KSpan;
            app.MethodDropDown_3.Value = app.CallingApp.AppData.SmoothingParameters.KMethod;
            
            setinteractivities(app)
        end

        % Button pushed function: DeletesmootheddataButton
        function DeletesmootheddataButtonPushed(app, event)
            app.CallingApp.AppData.SmoothingParameters.DeleteSmoothedData = 1;
            app.CallingApp.AppData.SmoothingParameters.Applied = 0;
            
            delete(app.UIFigure);
            
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checkvalues(app);
           
            app.CallingApp.AppData.SmoothingParameters.DeleteSmoothedData = 0;
            app.CallingApp.AppData.SmoothingParameters.Applied = 1;
            
            app.CallingApp.AppData.SmoothingParameters.CLSpan = app.FilterspanEditField.Value;
            app.CallingApp.AppData.SmoothingParameters.CLMethod = app.MethodDropDown.Value;
            
            app.CallingApp.AppData.SmoothingParameters.KInterpolate = app.InterpolateCheckBox.Value;
            app.CallingApp.AppData.SmoothingParameters.KInterpolateMethod = app.MethodDropDown_2.Value;
            app.CallingApp.AppData.SmoothingParameters.KSpan = app.FilterspanEditField_2.Value;
            app.CallingApp.AppData.SmoothingParameters.KMethod = app.MethodDropDown_3.Value;
            
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.AppData.SmoothingParameters.DeleteSmoothedData = 0;
            app.CallingApp.AppData.SmoothingParameters.Applied = 0;
            delete(app.UIFigure);
        end

        % Value changed function: FilterspanEditField
        function FilterspanEditFieldValueChanged(app, event)
            checkvalues(app)
            
            setinteractivities(app)
        end

        % Value changed function: FilterspanEditField_2
        function FilterspanEditField_2ValueChanged(app, event)
            checkvalues(app);
            
            setinteractivities(app)
        end

        % Value changed function: InterpolateCheckBox
        function InterpolateCheckBoxValueChanged(app, event)
            
            setinteractivities(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Smoothing Tool';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CracklinesmoothingLabel = uilabel(app.GridLayout);
            app.CracklinesmoothingLabel.Text = 'Crack line smoothing';
            app.FilterspanEditFieldLabel = uilabel(app.GridLayout);
            app.FilterspanEditFieldLabel.Text = 'Filter span';
            app.FilterspanEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterspanEditField.Limits = [1 Inf];
            app.FilterspanEditField.RoundFractionalValues = 'on';
            app.FilterspanEditField.ValueChangedFcn = createCallbackFcn(app, @FilterspanEditFieldValueChanged, true);
            app.FilterspanEditField.Value = 1;
            app.CrackkinematicsmoothingLabel = uilabel(app.GridLayout);
            app.CrackkinematicsmoothingLabel.Text = 'Crack kinematic smoothing';
            app.InterpolateCheckBox = uicheckbox(app.GridLayout);
            app.InterpolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolateCheckBoxValueChanged, true);
            app.InterpolateCheckBox.Text = 'Interpolate missing crack kinematic measurements';
            app.InterpolateCheckBox.Value = true;
            app.MethodLabel_2 = uilabel(app.GridLayout);
            app.MethodLabel_2.Text = ': Method';
            app.MethodDropDown_2 = uidropdown(app.GridLayout);
            app.MethodDropDown_2.Items = {'previous', 'next', 'nearest', 'linear', 'spline', 'pchip', 'makima'};
            app.MethodDropDown_2.Value = 'pchip';
            app.FilterspanEditField_2Label = uilabel(app.GridLayout);
            app.FilterspanEditField_2Label.Text = 'Filter span';
            app.FilterspanEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.FilterspanEditField_2.Limits = [1 Inf];
            app.FilterspanEditField_2.RoundFractionalValues = 'on';
            app.FilterspanEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilterspanEditField_2ValueChanged, true);
            app.FilterspanEditField_2.Value = 1;
            app.MethodLabel_3 = uilabel(app.GridLayout);
            app.MethodLabel_3.Text = ': Method';
            app.MethodDropDown_3 = uidropdown(app.GridLayout);
            app.MethodDropDown_3.Items = {'moving', 'lowess', 'loess', 'sgolay', 'rlowess', 'rloess'};
            app.MethodDropDown_3.Value = 'moving';
            app.MethodLabel = uilabel(app.GridLayout);
            app.MethodLabel.Text = ': Method';
            app.MethodDropDown = uidropdown(app.GridLayout);
            app.MethodDropDown.Items = {'moving', 'lowess', 'loess', 'sgolay', 'rlowess', 'rloess'};
            app.MethodDropDown.Value = 'moving';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DeletesmootheddataButton = uibutton(app.GridLayout, 'push');
            app.DeletesmootheddataButton.ButtonPushedFcn = createCallbackFcn(app, @DeletesmootheddataButtonPushed, true);
            app.DeletesmootheddataButton.Text = 'Delete smoothed data';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tool_Smoothing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end